classdef prototype1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Selectc3dfolderButton           matlab.ui.control.Button
        GeneratefilesButton             matlab.ui.control.Button
        Noc3dfolderselectedLabel        matlab.ui.control.Label
        FilesnotgeneratedLabel          matlab.ui.control.Label
        SelectpathButton                matlab.ui.control.Button
        SelectapathtoyouropensimmatlabdirectoryLabel  matlab.ui.control.Label
        ClickheretogeneratethetrcandmotfilesLabel  matlab.ui.control.Label
        OpenSimGUILabel                 matlab.ui.control.Label
        Selectthefolderwhichcontainsyourc3dfilesLabel  matlab.ui.control.Label
        SelectyourtrcResultsfolderLabel  matlab.ui.control.Label
        SelecttrcfolderButton           matlab.ui.control.Button
        NotrcfolderselectedLabel        matlab.ui.control.Label
        GetgaiteventsButton             matlab.ui.control.Button
        SuccessfulLabel                 matlab.ui.control.Label
        DFLOWButton                     matlab.ui.control.Button
        SelectDFLOWfolderButton         matlab.ui.control.Button
        ScalingButton                   matlab.ui.control.Button
        InverseKinematicsButton         matlab.ui.control.Button
        InverseDynamicsButton           matlab.ui.control.Button
        ScalingXMLs_TargetSearchButton  matlab.ui.control.Button
        SelectyourDatafolderButton      matlab.ui.control.Button
        IKXMLs_TargetSearchButton       matlab.ui.control.Button
        IDXMLs_TargetSearchButton       matlab.ui.control.Button
        PleaseselectthefollowingfoldersfromyourdirectoryLabel  matlab.ui.control.Label
        DFLOWsectionisoptionalLabel     matlab.ui.control.Label
        ScalingcompleteLabel            matlab.ui.control.Label
        InverseKinematicscompleteLabel  matlab.ui.control.Label
        InverseDynamicscompleteLabel    matlab.ui.control.Label
        ClickheretosetupallrelevantfilesButton  matlab.ui.control.Button
        StaticOptimisationButton        matlab.ui.control.Button
        DONELabel                       matlab.ui.control.Label
        CollectDataButton               matlab.ui.control.Button
        StaticOptimisationcompleteLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        c3d_path % path to selected c3d file
        OpensimMatlabPath % path to opensim matlab folder
        trc_path % path to trc folder
        app_path % path to app
        dflow_path % path to dflow folder
        xml_path % Description
        data_path % Description
        IKXML_path % Description
        IDXML_path % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Selectc3dfolderButton
        function Selectc3dfolderButtonPushed(app, event)
            app.c3d_path = uigetdir;  %open a c3d file
            set(app.GeneratefilesButton, 'Enable', 'on')
            set(app.Noc3dfolderselectedLabel, 'Text', 'A folder has been selected')
        end

        % Button pushed function: GeneratefilesButton
        function GeneratefilesButtonPushed(app, event)
            set(app.FilesnotgeneratedLabel, 'Text', 'Files are being generated now...')
            filter=1; cutoff=10; order=4;            
            dirIn = app.c3d_path;
            opensimPath = app.OpensimMatlabPath;
            appPath = app.app_path;
            runOpensim(dirIn,filter,cutoff,order,opensimPath,appPath);
            set(app.FilesnotgeneratedLabel, 'Text', 'Files have been generated!')
            set(app.SelecttrcfolderButton, 'Enable', 'on')
            set(app.GeneratefilesButton, 'Enable', 'off')
        end

        % Button pushed function: SelectpathButton
        function SelectpathButtonPushed(app, event)
            app.app_path = pwd
            app.OpensimMatlabPath = uigetdir;
        end

        % Button pushed function: SelecttrcfolderButton
        function SelecttrcfolderButtonPushed(app, event)
            app.trc_path = uigetdir;
            set(app.GetgaiteventsButton, 'Enable', 'on')
            set(app.NotrcfolderselectedLabel, 'Text', 'A folder has been selected')
        end

        % Button pushed function: GetgaiteventsButton
        function GetgaiteventsButtonPushed(app, event)
            source = app.trc_path;
            gaitEvents = trcGaitEvents(source);
            set(app.SuccessfulLabel, 'Visible', 'on')
            set(app.SelectDFLOWfolderButton, 'Enable', 'on')
            
        end

        % Button pushed function: DFLOWButton
        function DFLOWButtonPushed(app, event)
            plotting = 1; saving = 1;
            [missginFiles, BeltVel, Perturb] = dflow(app.dflow_path, plotting, saving);
        end

        % Button pushed function: SelectDFLOWfolderButton
        function SelectDFLOWfolderButtonPushed(app, event)
            app.dflow_path = uigetdir;
            set(app.DFLOWButton, 'Enable', 'on')
        end

        % Button pushed function: ScalingXMLs_TargetSearchButton
        function ScalingXMLs_TargetSearchButtonPushed(app, event)
            app.xml_path = uigetdir;
            set(app.ScalingButton, 'Enable', 'on')
        end

        % Button pushed function: ScalingButton
        function ScalingButtonPushed(app, event)
            changes.mass = 85; %mass of person in kilos - can be an option to select in app
            changes.lock = 1; %1 if feet joints are locked - can be an option to select
            ScalingProcess(app.xml_path, app.data_path, changes)
            set(app.InverseKinematicsButton, 'Enable', 'on')
            set(app.ScalingcompleteLabel, 'Visible', 'on')
        end

        % Button pushed function: SelectyourDatafolderButton
        function SelectyourDatafolderButtonPushed(app, event)
            app.data_path = uigetdir;
        end

        % Button pushed function: IKXMLs_TargetSearchButton
        function IKXMLs_TargetSearchButtonPushed(app, event)
            app.IKXML_path = uigetdir;
        end

        % Button pushed function: IDXMLs_TargetSearchButton
        function IDXMLs_TargetSearchButtonPushed(app, event)
            app.IDXML_path = uigetdir;
        end

        % Button pushed function: InverseKinematicsButton
        function InverseKinematicsButtonPushed(app, event)
            cd(app.app_path);
            source = app.IKXML_path;
            destination = app.data_path;
            setupAndRunIKBatchExample(source, destination)
            set(app.InverseDynamicsButton, 'Enable', 'on')
            set(app.InverseKinematicscompleteLabel, 'Visible', 'on')
        end

        % Button pushed function: InverseDynamicsButton
        function InverseDynamicsButtonPushed(app, event)
            cd(app.app_path);
            source = app.IDXML_path;
            destination = app.data_path;
            setupAndRunIDBatchExample(source, destination)
            set(app.InverseDynamicscompleteLabel, 'Visible', 'on')
        end

        % Button pushed function: 
        % ClickheretosetupallrelevantfilesButton
        function ClickheretosetupallrelevantfilesButtonPushed(app, event)
            FileType = {'trc', 'IK', 'ID', 'SO', 'GRF'};
            source = app.data_path;
            for i=1:length(FileType)
                Data_Collection_OpenSim_Auto(source,FileType{i});
                cd(app.app_path);
            end
            set(app.DONELabel, 'Visible', 'on')
            set(app.StaticOptimisationButton, 'Enable', 'on')
        end

        % Button pushed function: StaticOptimisationButton
        function StaticOptimisationButtonPushed(app, event)
            cd(app.app_path);
            source = app.data_path;
            destination = app.data_path;
            setupAndRunSOBatchExample(source, destination,-1)
            set(app.StaticOptimisationcompleteLabel, 'Visible', 'on')
        end

        % Button pushed function: CollectDataButton
        function CollectDataButtonPushed(app, event)
            FileType = {'trc', 'IK', 'ID', 'SO', 'GRF'};
            source = app.data_path;
            for i=1:length(FileType)
                Data_Collection_OpenSim_Auto(source,FileType{i});
                cd(app.app_path);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Selectc3dfolderButton = uibutton(app.UIFigure, 'push');
            app.Selectc3dfolderButton.ButtonPushedFcn = createCallbackFcn(app, @Selectc3dfolderButtonPushed, true);
            app.Selectc3dfolderButton.Enable = 'off';
            app.Selectc3dfolderButton.Text = 'Select c3d folder';
            app.GeneratefilesButton = uibutton(app.UIFigure, 'push');
            app.GeneratefilesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratefilesButtonPushed, true);
            app.GeneratefilesButton.Enable = 'off';
            app.GeneratefilesButton.Text = 'Generate files';
            app.Noc3dfolderselectedLabel = uilabel(app.UIFigure);
            app.Noc3dfolderselectedLabel.Text = 'No c3d folder selected';
            app.FilesnotgeneratedLabel = uilabel(app.UIFigure);
            app.FilesnotgeneratedLabel.Text = 'Files not generated';
            app.SelectpathButton = uibutton(app.UIFigure, 'push');
            app.SelectpathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpathButtonPushed, true);
            app.SelectpathButton.Text = 'Select path';
            app.SelectapathtoyouropensimmatlabdirectoryLabel = uilabel(app.UIFigure);
            app.SelectapathtoyouropensimmatlabdirectoryLabel.Text = 'Select the path to your opensim matlab directory: (...\Documents\OpenSim\4.0\Code\MATLAB)';
            app.ClickheretogeneratethetrcandmotfilesLabel = uilabel(app.UIFigure);
            app.ClickheretogeneratethetrcandmotfilesLabel.Text = 'Click here to generate the trc and mot files:';
            app.OpenSimGUILabel = uilabel(app.UIFigure);
            app.OpenSimGUILabel.Text = 'OpenSim GUI';
            app.Selectthefolderwhichcontainsyourc3dfilesLabel = uilabel(app.UIFigure);
            app.Selectthefolderwhichcontainsyourc3dfilesLabel.Text = 'Select the folder which contains your c3d files:';
            app.SelectyourtrcResultsfolderLabel = uilabel(app.UIFigure);
            app.SelectyourtrcResultsfolderLabel.Text = 'Select your trcResults folder: ';
            app.SelecttrcfolderButton = uibutton(app.UIFigure, 'push');
            app.SelecttrcfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttrcfolderButtonPushed, true);
            app.SelecttrcfolderButton.Enable = 'off';
            app.SelecttrcfolderButton.Text = 'Select trc folder';
            app.NotrcfolderselectedLabel = uilabel(app.UIFigure);
            app.NotrcfolderselectedLabel.Text = 'No trc folder selected';
            app.GetgaiteventsButton = uibutton(app.UIFigure, 'push');
            app.GetgaiteventsButton.ButtonPushedFcn = createCallbackFcn(app, @GetgaiteventsButtonPushed, true);
            app.GetgaiteventsButton.Enable = 'off';
            app.GetgaiteventsButton.Text = 'Get gait events';
            app.SuccessfulLabel = uilabel(app.UIFigure);
            app.SuccessfulLabel.Visible = 'off';
            app.SuccessfulLabel.Text = 'Successful!';
            app.DFLOWButton = uibutton(app.UIFigure, 'push');
            app.DFLOWButton.ButtonPushedFcn = createCallbackFcn(app, @DFLOWButtonPushed, true);
            app.DFLOWButton.Enable = 'off';
            app.DFLOWButton.Text = 'DFLOW';
            app.SelectDFLOWfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectDFLOWfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDFLOWfolderButtonPushed, true);
            app.SelectDFLOWfolderButton.Enable = 'off';
            app.SelectDFLOWfolderButton.Text = 'Select DFLOW folder';
            app.ScalingButton = uibutton(app.UIFigure, 'push');
            app.ScalingButton.ButtonPushedFcn = createCallbackFcn(app, @ScalingButtonPushed, true);
            app.ScalingButton.Enable = 'off';
            app.ScalingButton.Text = 'Scaling';
            app.InverseKinematicsButton = uibutton(app.UIFigure, 'push');
            app.InverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicsButtonPushed, true);
            app.InverseKinematicsButton.Enable = 'off';
            app.InverseKinematicsButton.Text = 'Inverse Kinematics';
            app.InverseDynamicsButton = uibutton(app.UIFigure, 'push');
            app.InverseDynamicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseDynamicsButtonPushed, true);
            app.InverseDynamicsButton.Enable = 'off';
            app.InverseDynamicsButton.Text = 'Inverse Dynamics';
            app.ScalingXMLs_TargetSearchButton = uibutton(app.UIFigure, 'push');
            app.ScalingXMLs_TargetSearchButton.ButtonPushedFcn = createCallbackFcn(app, @ScalingXMLs_TargetSearchButtonPushed, true);
            app.ScalingXMLs_TargetSearchButton.Text = 'ScalingXMLs_TargetSearch';
            app.SelectyourDatafolderButton = uibutton(app.UIFigure, 'push');
            app.SelectyourDatafolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectyourDatafolderButtonPushed, true);
            app.SelectyourDatafolderButton.Text = 'Select your ''Data'' folder';
            app.IKXMLs_TargetSearchButton = uibutton(app.UIFigure, 'push');
            app.IKXMLs_TargetSearchButton.ButtonPushedFcn = createCallbackFcn(app, @IKXMLs_TargetSearchButtonPushed, true);
            app.IKXMLs_TargetSearchButton.Text = 'IKXMLs_TargetSearch';
            app.IDXMLs_TargetSearchButton = uibutton(app.UIFigure, 'push');
            app.IDXMLs_TargetSearchButton.ButtonPushedFcn = createCallbackFcn(app, @IDXMLs_TargetSearchButtonPushed, true);
            app.IDXMLs_TargetSearchButton.Text = 'IDXMLs_TargetSearch';
            app.PleaseselectthefollowingfoldersfromyourdirectoryLabel = uilabel(app.UIFigure);
            app.PleaseselectthefollowingfoldersfromyourdirectoryLabel.Text = 'Please select the following folders from your directory:';
            app.DFLOWsectionisoptionalLabel = uilabel(app.UIFigure);
            app.DFLOWsectionisoptionalLabel.Text = 'DFLOW section is optional';
            app.ScalingcompleteLabel = uilabel(app.UIFigure);
            app.ScalingcompleteLabel.Visible = 'off';
            app.ScalingcompleteLabel.Text = 'Scaling complete';
            app.InverseKinematicscompleteLabel = uilabel(app.UIFigure);
            app.InverseKinematicscompleteLabel.Visible = 'off';
            app.InverseKinematicscompleteLabel.Text = 'Inverse Kinematics complete';
            app.InverseDynamicscompleteLabel = uilabel(app.UIFigure);
            app.InverseDynamicscompleteLabel.Visible = 'off';
            app.InverseDynamicscompleteLabel.Text = 'Inverse Dynamics complete';
            app.ClickheretosetupallrelevantfilesButton = uibutton(app.UIFigure, 'push');
            app.ClickheretosetupallrelevantfilesButton.ButtonPushedFcn = createCallbackFcn(app, @ClickheretosetupallrelevantfilesButtonPushed, true);
            app.ClickheretosetupallrelevantfilesButton.Text = 'Click here to set up all relevant files';
            app.StaticOptimisationButton = uibutton(app.UIFigure, 'push');
            app.StaticOptimisationButton.ButtonPushedFcn = createCallbackFcn(app, @StaticOptimisationButtonPushed, true);
            app.StaticOptimisationButton.Enable = 'off';
            app.StaticOptimisationButton.Text = 'Static Optimisation';
            app.DONELabel = uilabel(app.UIFigure);
            app.DONELabel.Visible = 'off';
            app.DONELabel.Text = 'DONE!';
            app.CollectDataButton = uibutton(app.UIFigure, 'push');
            app.CollectDataButton.ButtonPushedFcn = createCallbackFcn(app, @CollectDataButtonPushed, true);
            app.CollectDataButton.Text = 'Collect Data';
            app.StaticOptimisationcompleteLabel = uilabel(app.UIFigure);
            app.StaticOptimisationcompleteLabel.Visible = 'off';
            app.StaticOptimisationcompleteLabel.Text = 'Static Optimisation complete';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prototype1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end